classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Button       matlab.ui.control.Button
        Button2      matlab.ui.control.Button
        Button3      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Funcion para realizar iniciar la simulacion
            archivo_simulink='esquemasi.slx';
            %busca archivo de simulink en el directorio
            find_system('name',archivo_simulink);
            %iniciar la simulación
            set_param(gcs,'SimulationCommand','Start');
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            %pausa la simulación actual
            set_param(gcs,'SimulationCommand','Pause');
            pause(5)
            set_param(gcs,'SimulationCommand','Continue');
            pause(2)
            %parar las simulacón
            set_param(gcs,'SimulationCommand','Stop');
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            %para cerrar el archivo de simulink
            archivo_simulink='esquemasi.slx';
            set_param(gcs,'SimulationCommand','Stop');
            save_system(archivo_simulink);
            close_system(archivo_simulink);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            %ruta/objeto, parametros a asignar, donde num2str convierte de
            %formato número a string
            %set_param permite asignar un valor a un objeto
%             set_param('esquemasi/x','value',num2str(changingValue));
n1='esquemasi/'
n2='c'
a=strcat(n1,n2)
            set_param(a,'Gain',num2str(changingValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end